classdef data_reviews < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        AppendDataButton           matlab.ui.control.Button
        EndDateDatePicker          matlab.ui.control.DatePicker
        EndDateDatePickerLabel     matlab.ui.control.Label
        StartDateDatePicker        matlab.ui.control.DatePicker
        StartDateDatePickerLabel   matlab.ui.control.Label
        DeploymentDropDown         matlab.ui.control.DropDown
        DeploymentDropDownLabel    matlab.ui.control.Label
        StreamDropDown             matlab.ui.control.DropDown
        StreamDropDownLabel        matlab.ui.control.Label
        MethodDropDown             matlab.ui.control.DropDown
        MethodDropDownLabel        matlab.ui.control.Label
        SensorDropDown             matlab.ui.control.DropDown
        SensorDropDownLabel        matlab.ui.control.Label
        NodeDropDown               matlab.ui.control.DropDown
        NodeDropDownLabel          matlab.ui.control.Label
        SubsiteDropDown            matlab.ui.control.DropDown
        SubsiteDropDownLabel       matlab.ui.control.Label
        usedeploymentormanuallyenterdatesLabel  matlab.ui.control.Label
        ClearPlotButton            matlab.ui.control.Button
        FindGapsButton             matlab.ui.control.Button
        ClearParametersButton      matlab.ui.control.Button
        SelectandPlotParametersofInterestLabel  matlab.ui.control.Label
        LoadPreviouslyDownloadedDataFileLabel  matlab.ui.control.Label
        orLabel                    matlab.ui.control.Label
        SetupM2MDataRequestLabel   matlab.ui.control.Label
        ParametersListBox          matlab.ui.control.ListBox
        ParametersListBoxLabel     matlab.ui.control.Label
        NormalizeCheckBox          matlab.ui.control.CheckBox
        PlotButton                 matlab.ui.control.Button
        LoadDataButton             matlab.ui.control.Button
        RequestDataButton          matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        ClearAnnotationsButton     matlab.ui.control.Button
        LoadAnnotationsButton      matlab.ui.control.Button
        CreateAnnotationButton     matlab.ui.control.Button
        SaveAnnotationsButton      matlab.ui.control.Button
        DownloadAnnotationsButton  matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load the list of OOI sites, and prepare for selection
            app.SubsiteDropDown.Items = [{'Select'}; list_sites];
            app.SubsiteDropDown.Value = 'Select';
            
            % Disable other elements until ready.
            app.NodeDropDown.Enable = false;
            app.SensorDropDown.Enable = false;
            app.MethodDropDown.Enable = false;
            app.StreamDropDown.Enable = false;
            app.DeploymentDropDown.Enable = false;
            app.StartDateDatePicker.Enable = false;
            app.EndDateDatePicker.Enable = false;
            app.RequestDataButton.Enable = false;
            app.AppendDataButton.Enable = false;
            app.PlotButton.Enable = false;
            app.DownloadAnnotationsButton.Enable = false;
            app.CreateAnnotationButton.Enable = false;
            app.SaveAnnotationsButton.Enable = false;
            app.UITable.Data = [];
            
            % remove axis labels from the plot and set default plot handle
            xlabel(app.UIAxes, '', "Visible", "off");
            ylabel(app.UIAxes, '', "Visible", "off");
            setappdata(app.RightPanel, 'range_pick', []);
            setappdata(app.LeftPanel, 'start_date', '');
            setappdata(app.LeftPanel, 'end_date', '');
        end

        % Value changed function: SubsiteDropDown
        function SubsiteDropDownValueChanged(app, event)
            % select site and set nodes
            site = app.SubsiteDropDown.Value;
            app.NodeDropDown.Enable = true;
            app.NodeDropDown.Items = [{'Select'}; list_nodes(site)];
            app.NodeDropDown.Value = 'Select';
            app.NodeDropDown.Enable = true;
            
            % reset other dropdowns
            app.SensorDropDown.Items = {''};
            app.SensorDropDown.Enable = false;
            app.MethodDropDown.Items = {''};
            app.MethodDropDown.Enable = false;
            app.StreamDropDown.Items = {''};
            app.StreamDropDown.Enable = false;
            app.DeploymentDropDown.ItemsData = [];
            app.DeploymentDropDown.Items = {''};
            app.DeploymentDropDown.Enable = false;
            app.StartDateDatePicker.Enable = false;
            setappdata(app.LeftPanel, 'start_date', '');
            app.EndDateDatePicker.Enable = false;
            setappdata(app.LeftPanel, 'end_date', '');
            app.RequestDataButton.Enable = false;
            app.ParametersListBox.Items = {''};
            app.DownloadAnnotationsButton.Enable = false;
        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            site = app.SubsiteDropDown.Value;
            node = app.NodeDropDown.Value;
            app.SensorDropDown.Items = [{'Select'}; list_sensors(site, node)];
            app.SensorDropDown.Value = 'Select';
            app.SensorDropDown.Enable = true;
            
            % reset other dropdowns
            app.MethodDropDown.Items = {''};
            app.MethodDropDown.Enable = false;
            app.StreamDropDown.Items = {''};
            app.StreamDropDown.Enable = false;
            app.DeploymentDropDown.ItemsData = [];
            app.DeploymentDropDown.Items = {''};
            app.DeploymentDropDown.Enable = false;
            app.StartDateDatePicker.Enable = false;
            setappdata(app.LeftPanel, 'start_date', '');
            app.EndDateDatePicker.Enable = false;
            setappdata(app.LeftPanel, 'end_date', '');
            app.RequestDataButton.Enable = false;
            app.ParametersListBox.Items = {''};            
            app.DownloadAnnotationsButton.Enable = false;
        end

        % Value changed function: SensorDropDown
        function SensorDropDownValueChanged(app, event)
            site = app.SubsiteDropDown.Value;
            node = app.NodeDropDown.Value;
            sensor = app.SensorDropDown.Value;
            app.MethodDropDown.Items = [{'Select'}; list_methods(site, node, sensor)];
            app.MethodDropDown.Value = 'Select';
            app.MethodDropDown.Enable = true;
            
            app.DownloadAnnotationsButton.Enable = true;
            
            % reset other dropdowns
            app.StreamDropDown.Items = {''};
            app.StreamDropDown.Enable = false;
            app.DeploymentDropDown.ItemsData = [];
            app.DeploymentDropDown.Items = {''};
            app.DeploymentDropDown.Enable = false;
            app.StartDateDatePicker.Enable = false;
            setappdata(app.LeftPanel, 'start_date', '');
            app.EndDateDatePicker.Enable = false;
            setappdata(app.LeftPanel, 'end_date', '');
            app.RequestDataButton.Enable = false;
            app.ParametersListBox.Items = {''};
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            site = app.SubsiteDropDown.Value;
            node = app.NodeDropDown.Value;
            sensor = app.SensorDropDown.Value;
            method = app.MethodDropDown.Value;
            app.StreamDropDown.Items = [{'Select'}; list_streams(site, node, sensor, method)];
            app.StreamDropDown.Enable = true;

            app.DeploymentDropDown.ItemsData = [];
            app.DeploymentDropDown.Items = {''};
            app.DeploymentDropDown.Enable = false;
            app.StartDateDatePicker.Enable = false;
            setappdata(app.LeftPanel, 'start_date', '');
            app.EndDateDatePicker.Enable = false;
            setappdata(app.LeftPanel, 'end_date', '');
            app.RequestDataButton.Enable = false;
        end

        % Value changed function: StreamDropDown
        function StreamDropDownValueChanged(app, event)
            site = app.SubsiteDropDown.Value;
            node = app.NodeDropDown.Value;
            sensor = app.SensorDropDown.Value;
            deploy = list_deployments(site, node, sensor);
            app.DeploymentDropDown.Items = {''};
            app.DeploymentDropDown.ItemsData = [];
            app.DeploymentDropDown.Items = [{"Select"}; string(deploy)];
            app.DeploymentDropDown.ItemsData = [NaN; deploy];
            app.DeploymentDropDown.Enable = true;
            
            method = app.MethodDropDown.Value;
            if strcmp(method, 'telemetered') == 1 || strcmp(method, 'streamed') == 1 
                start = datetime("now") - days(7);
                stop = datetime("now");
            else
                deploy = app.DeploymentDropDown.ItemsData(end) - 1;
                [~, stop] = get_deployment_dates(site, node, sensor, deploy);
                stop = datetime(stop, 'ConvertFrom', 'datenum');
                start = stop - days(7);
            end

            app.StartDateDatePicker.Enable = true;
            app.StartDateDatePicker.Limits = [datetime([2013 11 1]) datetime("now")];
            app.StartDateDatePicker.Value = start;
            setappdata(app.LeftPanel, 'start_date', datestr(start, 'yyyy-mm-ddTHH:MM:SS.FFFZ'));

            app.EndDateDatePicker.Enable = true;
            app.EndDateDatePicker.Limits = [datetime([2013 11 1]) datetime("now")];
            app.EndDateDatePicker.Value = stop;
            setappdata(app.LeftPanel, 'end_date', datestr(stop, 'yyyy-mm-ddTHH:MM:SS.FFFZ'));

            app.RequestDataButton.Enable = true;
        end

        % Value changed function: DeploymentDropDown
        function DeploymentDropDownValueChanged(app, event)
            site = app.SubsiteDropDown.Value;
            node = app.NodeDropDown.Value;
            sensor = app.SensorDropDown.Value;
            deploy = app.DeploymentDropDown.Value;
            [start, stop] = get_deployment_dates(site, node, sensor, deploy);
            if stop == 0
                stop = now;
            end
            
            app.StartDateDatePicker.Value = datetime(start, 'ConvertFrom', 'datenum');
            setappdata(app.LeftPanel, 'start_date', datestr(start, 'yyyy-mm-ddTHH:MM:SS.FFFZ'));
            app.EndDateDatePicker.Value = datetime(stop, 'ConvertFrom', 'datenum');
            setappdata(app.LeftPanel, 'end_date', datestr(stop, 'yyyy-mm-ddTHH:MM:SS.FFFZ'));
            app.RequestDataButton.Enable = true;
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            start = app.StartDateDatePicker.Value;
            setappdata(app.LeftPanel, 'start_date', datestr(start, 'yyyy-mm-ddTHH:MM:SS.FFFZ'));
            if ~isempty(getappdata(app.LeftPanel, 'start_date')) && ~isempty(getappdata(app.LeftPanel, 'end_date'))
                app.RequestDataButton.Enable = true;
            end
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            stop = app.EndDateDatePicker.Value;
            setappdata(app.LeftPanel, 'end_date', datestr(stop, 'yyyy-mm-ddTHH:MM:SS.FFFZ'));            
            if ~isempty(getappdata(app.LeftPanel, 'start_date')) && ~isempty(getappdata(app.LeftPanel, 'end_date'))
                app.RequestDataButton.Enable = true;
            end            
        end

        % Button pushed function: RequestDataButton
        function RequestDataButtonPushed(app, event)
            persistent lastpath
            if isempty(lastpath) 
                lastpath = 0;
            end

            site = app.SubsiteDropDown.Value;
            node = app.NodeDropDown.Value;
            sensor = app.SensorDropDown.Value;
            method = app.MethodDropDown.Value;
            stream = app.StreamDropDown.Value;
            start = getappdata(app.LeftPanel, 'start_date');
            stop = getappdata(app.LeftPanel, 'end_date');
            filename = [site '.' node '.' sensor '.' method '.' stream '.' start(1:10) '-' stop(1:10) '.nc'];
            
            if lastpath == 0
                [filename, pathname] = uiputfile('*.nc', 'Save the requested data', filename);
            else
                [filename, pathname] = uiputfile('*.nc', 'Save the requested data', fullfile(lastpath, filename));
            end

            if pathname ~= 0
                lastpath = pathname;
                fname = fullfile(pathname, filename);
                cmdstr = ['python request_data.py -s ' site ' -n ' node ' -r ' sensor ' -m ' method ' -t ' stream ' -b ' start ' -e ' stop ' -f ' fname];
                [~, ~] = system(cmdstr, '-echo');
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            persistent lastpath
            if isempty(lastpath) 
                lastpath = 0;
            end

            if lastpath == 0
                [filename, pathname] = uigetfile('*.nc', 'Select NetCDF File');
            else
                [filename, pathname] = uigetfile('*.nc', 'Select NetCDF File', lastpath);
            end

            if pathname ~= 0
                % load the data file
                lastpath = pathname;
                data = nc_reader(fullfile(pathname, filename));
                
                % delete the string variables, we can't plot these
                data = data(:, vartype("numeric"));
                
                % set the list of available parameters to plot
                app.ParametersListBox.Items = data.Properties.VariableNames;
                setappdata(app.LeftPanel, 'ncdata', data);
                setappdata(app.LeftPanel, 'ncfile', filename);
                app.PlotButton.Enable = true;
                app.AppendDataButton.Enable = true;
            end
        end

        % Button pushed function: AppendDataButton
        function AppendDataButtonPushed(app, event)
            % load the current data
            curr_data = getappdata(app.LeftPanel, 'ncdata');
            
            % setup the path for the data to append
            persistent lastpath
            if isempty(lastpath) 
                lastpath = 0;
            end

            if lastpath == 0
                [filename, pathname] = uigetfile('*.nc', 'Select NetCDF File');
            else
                [filename, pathname] = uigetfile('*.nc', 'Select NetCDF File', lastpath);
            end

            if pathname ~= 0
                lastpath = pathname;
                % compare the current data and the new data to make sure they
                % are from the same dataset
                try
                    new_data = nc_reader(fullfile(pathname, filename));
                    new_data = new_data(:, vartype("numeric"));
                    data = [curr_data; new_data];
                catch ME
                   rethrow(ME)                    
                end
                % update the data for plotting
                setappdata(app.LeftPanel, 'ncdata', data);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % load the data and set start and stop dates
            data = getappdata(app.LeftPanel, 'ncdata');
            start_date = min(data.Time);
            stop_date = max(data.Time);

            % plot selected parameters
            parameters = app.ParametersListBox.Value;
            for i = 1:length(parameters)
                if app.NormalizeCheckBox.Value
                    parm = normalize(data{:, parameters{i}});
                else
                    parm = data{:, parameters{i}};
                end
                plot(app.UIAxes, data.Time, parm, '-o', 'MarkerSize', 4, 'Tag', 'plotData')
                %legend(app.UIAxes,parameters,'Interpreter','None','Box','Off','Location','Northeast');
                hold(app.UIAxes, 'on')
                % shade yellow behind suspect points from qartod
                parm_qartod = strcat(parameters{i}, '_qartod_results');
                qartod_check = contains(data.Properties.VariableNames,parm_qartod);
                if sum(qartod_check) == 1
                qartod = data{:, parm_qartod};
                suspect = find(qartod(1:end-1) > 1 & qartod(1:end-1) <= 3); 
                if ~isempty(suspect)
                    start = data.Time(suspect)-(data.Time(suspect) - data.Time(suspect-1))/2;
                    stop = data.Time(suspect) + (data.Time(suspect+1) - data.Time(suspect))/2;
                    h = fill(app.UIAxes, [start, start, stop, stop], ...
                            [app.UIAxes.YLim(1), app.UIAxes.YLim(2), ...
                            app.UIAxes.YLim(2), app.UIAxes.YLim(1)], ...
                            [1 1 0], 'EdgeColor', [1 1 0],'FaceAlpha', 0.3, 'EdgeAlpha', 0.3);
                    %set(get(get(h,'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
               end
                % shade red behind fail points from qartod
                fail = find(qartod(1:end-1) > 3 & qartod(1:end-1) <= 5);
                if ~isempty(fail)
                    start = data.Time(fail)-(data.Time(fail) - data.Time(fail-1))/2;
                    stop = data.Time(fail) + (data.Time(fail+1) - data.Time(fail))/2;
                    h = fill(app.UIAxes, [start, start, stop, stop], ...
                            [app.UIAxes.YLim(1), app.UIAxes.YLim(2), ...
                            app.UIAxes.YLim(2), app.UIAxes.YLim(1)], ...
                            [1 0 0], 'EdgeColor', [1 0 0],'FaceAlpha', 0.3, 'EdgeAlpha', 0.3);
                    %set(get(get(h,'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
                end
                end
            end
            
             % plot any applicable annotations
            if ~isempty(app.UITable.Data)
                annos = app.UITable.Data;
                back = [];
                for i = 1:size(annos, 1)
                    % Update for change to date format in annotations csv file
                    start = datetime(annos.beginDate(i), 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss', 'TimeZone', 'UTC');
                    stop = datetime(annos.endDate(i), 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss', 'TimeZone', 'UTC');
                    if ~isdatetime(stop)
                        stop = datetime("now", "TimeZone", "UTC");
                    end %if
                                        
                    if start >= start_date && stop <= stop_date
                        plotting = true;
                    elseif start < start_date && (stop >= start_date && stop <= stop_date)
                        start = start_date;
                        plotting = true;
                    elseif stop > stop_date && (start >= start_date && start <= stop_date)
                        stop = stop_date;
                        plotting = true;
                    elseif start < start_date && stop > stop_date
                        start = start_date;
                        stop = stop_date;
                        plotting = true;
                    else
                        plotting = false;
                    end %if
                    
                    if plotting
                        qc_flag = annos.qcFlag(i);
                        switch qc_flag
                            case 'not_operational'
                                facecolor = [0 1 1];
                            case 'not_available'
                                facecolor = [0 0 1];
                            case 'suspect'
                                facecolor = [1 1 0];
                            case 'fail'
                                facecolor = [1 0 0];
                            case 'pass'
                                facecolor = [0 1 0];
                            otherwise
                                facecolor = [0 0 0];
                        end % switch
                        h = fill(app.UIAxes, [start, start, stop, stop], ...
                            [app.UIAxes.YLim(1), app.UIAxes.YLim(2), ...
                            app.UIAxes.YLim(2), app.UIAxes.YLim(1)], ...
                            facecolor, 'FaceAlpha', 0.15, 'EdgeAlpha', 0.15);
                        %set(get(get(h,'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
                        back = [back; h];
                        hold(app.UIAxes, "on")
                    end %if
                end %for
                if ~isempty(back)
                    ch = get(app.UIAxes, 'Children');
                    [~, neworder] = sort(ismember(ch, back));
                    set(app.UIAxes, 'Children', ch(neworder));
                end %if
            end %if
            hold(app.UIAxes, 'off')
            
            % set the title and wrap up
            title(app.UIAxes, getappdata(app.LeftPanel, 'ncfile'), 'Interpreter', 'none');
            app.CreateAnnotationButton.Enable = true;
        end

        % Button pushed function: DownloadAnnotationsButton
        function DownloadAnnotationsButtonPushed(app, event)
            site = app.SubsiteDropDown.Value;
            node = app.NodeDropDown.Value;
            sensor = app.SensorDropDown.Value;
            annotations = get_annotations(site, node, sensor);
            if ~isempty(annotations)
                app.UITable.Data = annotations;                
            end
            
            % enable the create and save annotations buttons
            app.CreateAnnotationButton.Enable = true;
            app.SaveAnnotationsButton.Enable = true;
        end

        % Button pushed function: LoadAnnotationsButton
        function LoadAnnotationsButtonPushed(app, event)
            persistent lastpath
            if isempty(lastpath) 
                lastpath = 0;
            end

            if lastpath == 0
                [filename, pathname] = uigetfile('*.csv', 'Select Annotations File');
            else
                [filename, pathname] = uigetfile('*.csv', 'Select Annotations File', lastpath);
            end

            if pathname ~= 0
                lastpath = pathname;
                opts = detectImportOptions(fullfile(pathname, filename));
                opts.VariableNames = {'id', 'subsite', 'node', 'sensor', ...
                    'stream', 'method', 'parameters', 'beginDate', ...
                    'endDate', 'exclusionFlag', 'qcFlag', 'source', ...
                    'annotation'};
                opts.VariableTypes = {'double', 'string', 'string', ...
                    'string', 'string', 'string', 'string', 'string', ...
                    'string', 'string', 'string', 'string', 'string'};
                app.UITable.Data = readtable(fullfile(pathname, filename), opts);

                % enable the create and save annotations buttons
                app.CreateAnnotationButton.Enable = true;
                app.SaveAnnotationsButton.Enable = true;
            end
        end

        % Button pushed function: CreateAnnotationButton
        function CreateAnnotationButtonPushed(app, event)
            varNames = {'id', 'subsite', 'node', 'sensor', 'stream', 'method', ...
                'parameters', 'beginDate', 'endDate', 'exclusionFlag', 'qcFlag', ...
                'source', 'annotation'};
            varTypes = {'double', 'string', 'string', 'string', 'string', 'string', ...
                'string', 'string', 'string', 'string', 'string', 'string', 'string'};
            new = table('Size', [1, 13], 'VariableTypes', varTypes, 'VariableNames', varNames);
            annotations = [app.UITable.Data; new];
            
            % set defaults
            site = app.SubsiteDropDown.Value;
            if ~isempty(site)
                annotations.subsite(end) = site;
            end
            node = app.NodeDropDown.Value;
            if ~isempty(node)
                annotations.node(end) = node;
            end
            sensor = app.SensorDropDown.Value;
            if ~isempty(sensor)
                annotations.sensor(end) = sensor;
            end
            
            % select new points
            zoom(app.UIAxes, 'off')
            pan(app.UIAxes, 'off')
            setappdata(app.RightPanel, 'range_pick', [])
            rng = ones(1, 2) * NaN;
            cnt = 1;
            while isnan(rng(2))
                if ~isempty(getappdata(app.RightPanel, 'range_pick'))
                    % pick up the new data point
                    range_pick = getappdata(app.RightPanel, 'range_pick');
                    rng(cnt) = range_pick(1, 1);
                    cnt = cnt + 1;
                    
                    % clear and reset
                    setappdata(app.RightPanel, 'range_pick', [])
                end
                pause(0.1)
            end
            
            % set date range and update the table
            rng =  sort(rng);
            annotations.beginDate(end) = datestr(num2ruler(rng(1), app.UIAxes.XAxis), 'yyyy-mm-ddTHH:MM:SS');
            annotations.endDate(end) = datestr(num2ruler(rng(2), app.UIAxes.XAxis), 'yyyy-mm-ddTHH:MM:SS');
            annotations.exclusionFlag(end) = 'FALSE';
            app.UITable.Data = annotations;
            app.SaveAnnotationsButton.Enable = true;
        end

        % Button pushed function: SaveAnnotationsButton
        function SaveAnnotationsButtonPushed(app, event)
            persistent lastpath
            if isempty(lastpath) 
                lastpath = 0;
            end

            if lastpath == 0
                [filename, pathname] = uiputfile('*.csv', 'Save the Annotations');
            else
                [filename, pathname] = uiputfile('*.csv', 'Save the Annotations', lastpath);
            end

            if pathname ~= 0
                lastpath = pathname;
                writetable(app.UITable.Data, fullfile(pathname, filename))
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % Create if statement that determines if the user clicked on the line in top UIAxes. If they didn't, do nothing
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag, 'plotData')
                % Store the CurrentPiont of the UIAxes
                setappdata(app.RightPanel, 'range_pick', app.UIAxes.CurrentPoint);
            end
        end

        % Button pushed function: ClearParametersButton
        function ClearParametersButtonPushed(app, event)
            app.ParametersListBox.Items=cellstr(num2str([]));         
        end

        % Button pushed function: FindGapsButton
        function FindGapsButtonPushed(app, event)
            data = getappdata(app.LeftPanel, 'ncdata');
            chkgaps = hours(diff(data.Time)) >= 72;
            idx = find(chkgaps);
            if ~isempty(idx)
            for i = 1:length(idx)
                left = data.Time(idx(i));
                right = data.Time(idx(i)+1);
                gap = hours(right-left);
                tt = ['Data gap of ', num2str(gap),' hours in this deployment'];
                disp(' ')
                disp(tt)
                disp(' ')
                if ~isempty(app.UITable.Data)
                    annos = app.UITable.Data;
                    start = datenum(annos.beginDate);
                    stop = datenum(annos.endDate);
                    if ~(datenum(left) >= start & datenum(right) <= stop)
                         % Add annotation if not already included in table
                        tt = ['Adding data gap to annotations for ',datestr(left,31),' to ',datestr(right,31)];
                        disp(tt)
                        disp(' ')
                        varNames = {'id', 'subsite', 'node', 'sensor', 'stream', 'method', ...
                        'parameters', 'beginDate', 'endDate', 'exclusionFlag', 'qcFlag', ...
                        'source', 'annotation'};
                        varTypes = {'double', 'string', 'string', 'string', 'string', 'string', ...
                        'string', 'string', 'string', 'string', 'string', 'string', 'string'};
                        new = table('Size', [1, 13], 'VariableTypes', varTypes, 'VariableNames', varNames);
                        annotations = [app.UITable.Data; new];
                        % set defaults
                        site = app.SubsiteDropDown.Value;
                        if ~isempty(site) && ~strcmp(site,'Select')
                            annotations.subsite(end) = site;
                        else
                            annotations.subsite(end) = annotations.subsite(1);
                        end
                        node = app.NodeDropDown.Value;
                        if ~isempty(node) && ~strcmp(node,'Select')
                            annotations.node(end) = node;
                        end
                        sensor = app.SensorDropDown.Value;
                        if ~isempty(sensor) && ~strcmp(sensor,'Select')
                            annotations.sensor(end) = sensor;
                        end
                        % set date range and update the table
                        annotations.beginDate(end) = datestr(left, 'yyyy-mm-ddTHH:MM:SS');
                        annotations.endDate(end) = datestr(right, 'yyyy-mm-ddTHH:MM:SS');
                        annotations.exclusionFlag(end) = 'False';
                        annotations.qcFlag(end) = 'not_available';
                        annotations.source(end) = 'kuebelb@oregonstate.edu';
                        % user prompt for annotation text
                        comment = input('Type the annotation for this data gap: ','s');
                        annotations.annotation(end) = comment;
                        app.UITable.Data = annotations;
                        app.SaveAnnotationsButton.Enable = true;   
                    end
                end
            end
            else
                 disp('No large data gaps for this deployment')
                 disp(' ')
            end
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: ClearAnnotationsButton
        function ClearAnnotationsButtonPushed(app, event)
            app.UITable.Data = [];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {751, 751};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {249, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.RequestDataButton = uibutton(app.GridLayout2, 'push');
            app.RequestDataButton.ButtonPushedFcn = createCallbackFcn(app, @RequestDataButtonPushed, true);
            app.RequestDataButton.Tooltip = {'Submit a request for the data set defined above.'};
            app.RequestDataButton.Text = 'Request Data';
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Load data from a local NetCDF file.'};
            app.LoadDataButton.Text = 'Load Data';
            app.PlotButton = uibutton(app.GridLayout2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tooltip = {'Plot the selected parameters'};
            app.PlotButton.Text = 'Plot';
            app.NormalizeCheckBox = uicheckbox(app.GridLayout2);
            app.NormalizeCheckBox.Tooltip = {'Normalize the data to 0 mean and 1 standard deviation.'};
            app.NormalizeCheckBox.Text = 'Normalize';
            app.ParametersListBoxLabel = uilabel(app.GridLayout2);
            app.ParametersListBoxLabel.Tooltip = {''};
            app.ParametersListBoxLabel.Text = 'Parameters';
            app.ParametersListBox = uilistbox(app.GridLayout2);
            app.ParametersListBox.Items = {};
            app.ParametersListBox.Multiselect = 'on';
            app.ParametersListBox.Tooltip = {'Available parameters'};
            app.ParametersListBox.Value = {};
            app.SetupM2MDataRequestLabel = uilabel(app.GridLayout2);
            app.SetupM2MDataRequestLabel.Tooltip = {''};
            app.SetupM2MDataRequestLabel.Text = 'Setup M2M Data Request';
            app.orLabel = uilabel(app.GridLayout2);
            app.orLabel.Text = '-- or --';
            app.LoadPreviouslyDownloadedDataFileLabel = uilabel(app.GridLayout2);
            app.LoadPreviouslyDownloadedDataFileLabel.Text = {'Load Previously Downloaded'; 'Data File'};
            app.SelectandPlotParametersofInterestLabel = uilabel(app.GridLayout2);
            app.SelectandPlotParametersofInterestLabel.Tooltip = {''};
            app.SelectandPlotParametersofInterestLabel.Text = {'Select and Plot'; 'Parameters of Interest'};
            app.ClearParametersButton = uibutton(app.GridLayout2, 'push');
            app.ClearParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ClearParametersButtonPushed, true);
            app.ClearParametersButton.Text = 'Clear Parameters';
            app.FindGapsButton = uibutton(app.GridLayout2, 'push');
            app.FindGapsButton.ButtonPushedFcn = createCallbackFcn(app, @FindGapsButtonPushed, true);
            app.FindGapsButton.Text = 'Find Gaps';
            app.ClearPlotButton = uibutton(app.GridLayout2, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.usedeploymentormanuallyenterdatesLabel = uilabel(app.GridLayout2);
            app.usedeploymentormanuallyenterdatesLabel.Tooltip = {''};
            app.usedeploymentormanuallyenterdatesLabel.Text = '(use deployment # or manually enter dates)';
            app.SubsiteDropDownLabel = uilabel(app.GridLayout2);
            app.SubsiteDropDownLabel.Tooltip = {''};
            app.SubsiteDropDownLabel.Text = 'Subsite';
            app.SubsiteDropDown = uidropdown(app.GridLayout2);
            app.SubsiteDropDown.Items = {};
            app.SubsiteDropDown.ValueChangedFcn = createCallbackFcn(app, @SubsiteDropDownValueChanged, true);
            app.SubsiteDropDown.Tooltip = {'Select the OOI site code'};
            app.SubsiteDropDown.Value = {};
            app.NodeDropDownLabel = uilabel(app.GridLayout2);
            app.NodeDropDownLabel.Tooltip = {''};
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.GridLayout2);
            app.NodeDropDown.Items = {};
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.NodeDropDown.Tooltip = {'Select the node, or sublocation, within a site'};
            app.NodeDropDown.Value = {};
            app.SensorDropDownLabel = uilabel(app.GridLayout2);
            app.SensorDropDownLabel.Tooltip = {''};
            app.SensorDropDownLabel.Text = 'Sensor';
            app.SensorDropDown = uidropdown(app.GridLayout2);
            app.SensorDropDown.Items = {};
            app.SensorDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorDropDownValueChanged, true);
            app.SensorDropDown.Tooltip = {'Select the sensor of interest'};
            app.SensorDropDown.Value = {};
            app.MethodDropDownLabel = uilabel(app.GridLayout2);
            app.MethodDropDownLabel.Tooltip = {''};
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout2);
            app.MethodDropDown.Items = {};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Tooltip = {'Select the data delivery method'};
            app.MethodDropDown.Value = {};
            app.StreamDropDownLabel = uilabel(app.GridLayout2);
            app.StreamDropDownLabel.Tooltip = {''};
            app.StreamDropDownLabel.Text = 'Stream';
            app.StreamDropDown = uidropdown(app.GridLayout2);
            app.StreamDropDown.Items = {};
            app.StreamDropDown.ValueChangedFcn = createCallbackFcn(app, @StreamDropDownValueChanged, true);
            app.StreamDropDown.Tooltip = {'Select the stream, or dataset name, of interest'};
            app.StreamDropDown.Value = {};
            app.DeploymentDropDownLabel = uilabel(app.GridLayout2);
            app.DeploymentDropDownLabel.Tooltip = {''};
            app.DeploymentDropDownLabel.Text = 'Deployment';
            app.DeploymentDropDown = uidropdown(app.GridLayout2);
            app.DeploymentDropDown.Items = {};
            app.DeploymentDropDown.ValueChangedFcn = createCallbackFcn(app, @DeploymentDropDownValueChanged, true);
            app.DeploymentDropDown.Tooltip = {'Select the deployment number to set the start and end dates'};
            app.DeploymentDropDown.Value = {};
            app.StartDateDatePickerLabel = uilabel(app.GridLayout2);
            app.StartDateDatePickerLabel.Tooltip = {''};
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.GridLayout2);
            app.StartDateDatePicker.Limits = [datetime([2014 1 1]) datetime([2050 12 31])];
            app.StartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.StartDateDatePicker.Tooltip = {'Select the starting date'};
            app.EndDateDatePickerLabel = uilabel(app.GridLayout2);
            app.EndDateDatePickerLabel.Tooltip = {''};
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.GridLayout2);
            app.EndDateDatePicker.Limits = [datetime([2014 1 1]) datetime([2050 12 31])];
            app.EndDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.EndDateDatePicker.Tooltip = {'Select the ending date'};
            app.AppendDataButton = uibutton(app.GridLayout2, 'push');
            app.AppendDataButton.ButtonPushedFcn = createCallbackFcn(app, @AppendDataButtonPushed, true);
            app.AppendDataButton.Tooltip = {'Load data from a local NetCDF file.'};
            app.AppendDataButton.Text = 'Append Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'site.node.sensor.method.stream')
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'id'; 'subsite'; 'node'; 'sensor'; 'stream'; 'method'; 'parameters'; 'beginDate'; 'endDate'; 'exclusionFlag'; 'qcFlag'; 'source'; 'annotation'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.Tooltip = {'Annotations applicable to the system of interest.'};
            app.DownloadAnnotationsButton = uibutton(app.GridLayout3, 'push');
            app.DownloadAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadAnnotationsButtonPushed, true);
            app.DownloadAnnotationsButton.Tooltip = {'Download annotations from the OOI database.'};
            app.DownloadAnnotationsButton.Text = 'Download Annotations';
            app.SaveAnnotationsButton = uibutton(app.GridLayout3, 'push');
            app.SaveAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnnotationsButtonPushed, true);
            app.SaveAnnotationsButton.Tooltip = {'Save loaded annotations to disk.'};
            app.SaveAnnotationsButton.Text = 'Save Annotations';
            app.CreateAnnotationButton = uibutton(app.GridLayout3, 'push');
            app.CreateAnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAnnotationButtonPushed, true);
            app.CreateAnnotationButton.Tooltip = {'Add an annotation.'};
            app.CreateAnnotationButton.Text = 'Create Annotation';
            app.LoadAnnotationsButton = uibutton(app.GridLayout3, 'push');
            app.LoadAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnnotationsButtonPushed, true);
            app.LoadAnnotationsButton.Tooltip = {'Load annotations from a locally stored *.csv file.'};
            app.LoadAnnotationsButton.Text = 'Load Annotations';
            app.ClearAnnotationsButton = uibutton(app.GridLayout3, 'push');
            app.ClearAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAnnotationsButtonPushed, true);
            app.ClearAnnotationsButton.Text = 'Clear Annotations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_reviews

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end